classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GetFileButton             matlab.ui.control.Button
        DatainFileEditFieldLabel  matlab.ui.control.Label
        DatainFileEditField       matlab.ui.control.EditField
        FileNameTextAreaLabel     matlab.ui.control.Label
        FileNameTextArea          matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            temp = [];
        end

        % Callback function: DatainFileEditField, FileNameTextArea, 
        % GetFileButton
        function GetFileButtonPushed(app, event)
            startupFcn(app);
            fileName = uigetfile('*.txt');
            fid = fopen(fileName);
            i = 1;
%             temp = {};
            while i > 0
            test = fgetl(fid);
            i = numel(test);
            
            if i ~= 0 %if there are no numerical values in line (therefore a header)
                temp(end+1) = test;
            end
            app.FileNameTextArea.Value = fileName;
            app.DatainFileEditField.Value = temp;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GetFileButton = uibutton(app.UIFigure, 'push');
            app.GetFileButton.ButtonPushedFcn = createCallbackFcn(app, @GetFileButtonPushed, true);
            app.GetFileButton.Text = 'Get File';
            app.DatainFileEditFieldLabel = uilabel(app.UIFigure);
            app.DatainFileEditFieldLabel.Text = 'Data in File';
            app.DatainFileEditField = uieditfield(app.UIFigure, 'text');
            app.DatainFileEditField.ValueChangedFcn = createCallbackFcn(app, @GetFileButtonPushed, true);
            app.FileNameTextAreaLabel = uilabel(app.UIFigure);
            app.FileNameTextAreaLabel.Text = 'File Name';
            app.FileNameTextArea = uitextarea(app.UIFigure);
            app.FileNameTextArea.ValueChangedFcn = createCallbackFcn(app, @GetFileButtonPushed, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end